classdef app < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                      matlab.ui.Figure
        DatasetDropDown               matlab.ui.control.DropDown
        DatasetDropDownLabel          matlab.ui.control.Label
        OutputTextArea                matlab.ui.control.TextArea
        OutputTextAreaLabel           matlab.ui.control.Label
        btn_TestarImagem              matlab.ui.control.Button
        btn_CarregarRede              matlab.ui.control.Button
        btn_GravarRede                matlab.ui.control.Button
        btn_TreinarRede               matlab.ui.control.Button
        DropDown_FAtivacao            matlab.ui.control.DropDown
        FunaodeativacaoDropDownLabel  matlab.ui.control.Label
        DropDown_FTreino              matlab.ui.control.DropDown
        FunodetreinoDropDownLabel     matlab.ui.control.Label
        EditField_NumNeuronios        matlab.ui.control.EditField
        Neuroniosdecadacamadaex105EditField_2Label  matlab.ui.control.Label
        Spin_NumCamadas               matlab.ui.control.Spinner
        NumerodeCamadasOcultasLabel   matlab.ui.control.Label
    end

    
    properties (Access = private)
        redeTreinada % Guardar a rede Treinada
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: btn_TreinarRede
        function btn_TreinarRedeButtonPushed(app, event)
    try
        % 1. Ler parâmetros do GUI
        numCamadas = app.Spin_NumCamadas.Value;
        neuroniosStr = app.EditField_NumNeuronios.Value;
        topologia = str2num(neuroniosStr); 
        if numel(topologia) ~= numCamadas
            app.OutputTextArea.Value = 'Erro: Nº de neurónios não corresponde ao nº de camadas.';
            return;
        end
        funcTreino = app.DropDown_FTreino.Value;
        funcAtivacao = app.DropDown_FAtivacao.Value;

        % 2. Carregar dados
        switch app.DatasetDropDown.Value
            case 'start'
                [inputs, targets] = convertImage_start();
            case 'train'
                [inputs, targets] = convertImage_train();
            case 'test'
                [inputs, targets] = convertImage_test();
            case 'all'
                [inputs, targets] = convertImage_all();
            otherwise
                error("Dataset inválido selecionado.");
        end

        % 3. Criar rede com a topologia definida
        net = feedforwardnet(topologia);

        % 4. Configurar funções
        net.trainFcn = funcTreino;
        for i = 1:length(net.layers)-1
            net.layers{i}.transferFcn = funcAtivacao;
        end
        net.layers{end}.transferFcn = 'purelin';  % saída clássica

        % 5. Parâmetros de treino
        net.trainParam.epochs = 20;
        net.divideParam.trainRatio = 0.7;
        net.divideParam.valRatio = 0.15;
        net.divideParam.testRatio = 0.15;

        % 6. Treinar
        [net, tr] = train(net, inputs, targets);

        % 7. Avaliar
        outputs = net(inputs);
        [~, predGlobal] = max(outputs);
        [~, trueGlobal] = max(targets);
        accGlobal = sum(predGlobal == trueGlobal) / length(trueGlobal);

        % Avaliar no conjunto de teste
        testInputs = inputs(:, tr.testInd);
        testTargets = targets(:, tr.testInd);
        testOutputs = net(testInputs);
        [~, predTest] = max(testOutputs);
        [~, trueTest] = max(testTargets);
        accTeste = sum(predTest == trueTest) / length(trueTest);

        % 8. Mostrar resultados
msg = sprintf(['Rede treinada com sucesso!\n' ...
               'Dataset: %s\n' ...
               'Topologia: [%s]\n' ...
               'Função de treino: %s\n' ...
               'Função de ativação: %s\n' ...
               'Precisão GLOBAL: %.2f%%\n' ...
               'Precisão TESTE: %.2f%%'], ...
               app.DatasetDropDown.Value, ...
               num2str(topologia), ...
               funcTreino, funcAtivacao, ...
               accGlobal*100, accTeste*100);
app.OutputTextArea.Value = msg;

        % Guardar rede no app para ser usada por outros botões
        %app.UserData.netTreinada = net;
        app.redeTreinada = net; % para guardar nas "variaveis globais"

        
        figure;
        plotconfusion(testTargets, testOutputs);
        title(sprintf('Matriz Confusão - [%s]', num2str(topologia)));

    catch ME
        app.OutputTextArea.Value = ['Erro ao treinar: ', ME.message];
    end
        end

        % Button pushed function: btn_GravarRede
        function btn_GravarRedeButtonPushed(app, event)
            try
        if isempty(app.redeTreinada)
            app.OutputTextArea.Value = "Erro: Nenhuma rede treinada para gravar.";
            return;
        end

        [file, path] = uiputfile('redeTreinada.mat', 'Guardar Rede Treinada');
        if isequal(file, 0)
            app.OutputTextArea.Value = "Gravação cancelada.";
            return;
        end

        net = app.redeTreinada; 
        save(fullfile(path, file), 'net');
        app.OutputTextArea.Value = sprintf("Rede guardada em: %s", fullfile(path, file));
    catch ME
        app.OutputTextArea.Value = sprintf("Erro ao gravar: %s", ME.message);
    
            end 
        end

        % Button pushed function: btn_CarregarRede
        function btn_CarregarRedeButtonPushed(app, event)
        try
        [file, path] = uigetfile('*.mat', 'Selecionar Ficheiro de Rede');
        if isequal(file, 0)
            app.OutputTextArea.Value = "Carregamento cancelado.";
            return;
        end

        data = load(fullfile(path, file));
        
        if isfield(data, 'net')
            app.redeTreinada = data.net;
            app.OutputTextArea.Value = sprintf("Rede carregada com sucesso de: %s", fullfile(path, file));
        else
            app.OutputTextArea.Value = "Erro: O ficheiro não contém uma rede válida.";
        end
    catch ME
        app.OutputTextArea.Value = sprintf("Erro ao carregar rede: %s", ME.message);
        end

        end

        % Button pushed function: btn_TestarImagem
        function btn_TestarImagemButtonPushed(app, event)
        try
    if isempty(app.redeTreinada)
        app.OutputTextArea.Value = "Erro: Nenhuma rede carregada ou treinada.";
        return;
    end

    [file, path] = uigetfile({'*.png;*.jpg;*.jpeg;*.bmp'}, 'Selecionar Imagem');
    if isequal(file, 0)
        app.OutputTextArea.Value = "Seleção de imagem cancelada.";
        return;
    end

    % Carregar e converter a imagem
    imgPath = fullfile(path, file);
    img = imread(imgPath);
    if size(img, 3) == 3
        img = rgb2gray(img);
    end
    img = imbinarize(imresize(img, [25 25])); % Redimensionar se necessário
    imgVect = double(img(:)); % Converter para vetor coluna

    % Aplicar rede
    output = app.redeTreinada(imgVect);
    [~, predictedClass] = max(output);

    % Lista de nomes das classes
    classNames = {'circle', 'kite', 'parallelogram', 'square', 'trapezoid', 'triangle'};

    % Mostrar resultado
    app.OutputTextArea.Value = sprintf("Classe prevista: %d - %s", predictedClass, classNames{predictedClass});

catch ME
    app.OutputTextArea.Value = sprintf("Erro ao testar imagem: %s", ME.message);
end
        end

        % Value changed function: DatasetDropDown
        function DatasetDropDownValueChanged(app, event)
            value = app.DatasetDropDown.Value;
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.NumerodeCamadasOcultasLabel = uilabel(app.UIFigure);
            app.NumerodeCamadasOcultasLabel.Text = 'Nº de Camadas Ocultas:';
            app.Spin_NumCamadas = uispinner(app.UIFigure);
            app.Spin_NumCamadas.Value = 1;
            app.Neuroniosdecadacamadaex105EditField_2Label = uilabel(app.UIFigure);
            app.Neuroniosdecadacamadaex105EditField_2Label.Text = 'Neuronios de cada camada (ex:[10 5])';
            app.EditField_NumNeuronios = uieditfield(app.UIFigure, 'text');
            app.EditField_NumNeuronios.Value = '10';
            app.FunodetreinoDropDownLabel = uilabel(app.UIFigure);
            app.FunodetreinoDropDownLabel.Text = 'Função de treino';
            app.DropDown_FTreino = uidropdown(app.UIFigure);
            app.DropDown_FTreino.Items = {'trainlm', 'traingd', 'trainscg'};
            app.DropDown_FTreino.Value = 'trainlm';
            app.FunaodeativacaoDropDownLabel = uilabel(app.UIFigure);
            app.FunaodeativacaoDropDownLabel.Text = 'Funçao de ativacao';
            app.DropDown_FAtivacao = uidropdown(app.UIFigure);
            app.DropDown_FAtivacao.Items = {'logsig', 'tansig', 'purelin'};
            app.DropDown_FAtivacao.Value = 'logsig';
            app.btn_TreinarRede = uibutton(app.UIFigure, 'push');
            app.btn_TreinarRede.ButtonPushedFcn = createCallbackFcn(app, @btn_TreinarRedeButtonPushed, true);
            app.btn_TreinarRede.Text = 'Treinar Rede';
            app.btn_GravarRede = uibutton(app.UIFigure, 'push');
            app.btn_GravarRede.ButtonPushedFcn = createCallbackFcn(app, @btn_GravarRedeButtonPushed, true);
            app.btn_GravarRede.Text = 'Gravar Rede';
            app.btn_CarregarRede = uibutton(app.UIFigure, 'push');
            app.btn_CarregarRede.ButtonPushedFcn = createCallbackFcn(app, @btn_CarregarRedeButtonPushed, true);
            app.btn_CarregarRede.Text = 'Carregar Rede';
            app.btn_TestarImagem = uibutton(app.UIFigure, 'push');
            app.btn_TestarImagem.ButtonPushedFcn = createCallbackFcn(app, @btn_TestarImagemButtonPushed, true);
            app.btn_TestarImagem.Text = 'Testar Imagem';
            app.OutputTextAreaLabel = uilabel(app.UIFigure);
            app.OutputTextAreaLabel.Text = 'Output:';
            app.OutputTextArea = uitextarea(app.UIFigure);
            app.DatasetDropDownLabel = uilabel(app.UIFigure);
            app.DatasetDropDownLabel.Text = 'Dataset: ';
            app.DatasetDropDown = uidropdown(app.UIFigure);
            app.DatasetDropDown.Items = {'start', 'train', 'test', 'all'};
            app.DatasetDropDown.ValueChangedFcn = createCallbackFcn(app, @DatasetDropDownValueChanged, true);
            app.DatasetDropDown.Value = 'start';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = app

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end